classdef P1___7Equipo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GuardarImagenButton           matlab.ui.control.Button
        PROCESADORDEIMAGENESLabel     matlab.ui.control.Label
        CargarImagenButton            matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        OperacionesBsicasTab          matlab.ui.container.Tab
        DeshacerButton                matlab.ui.control.Button
        LabelInfoProcesada            matlab.ui.control.Label
        LabelInfoOriginal             matlab.ui.control.Label
        AnlisisdepxelesPanel          matlab.ui.container.Panel
        CalcularDistanciasButton      matlab.ui.control.Button
        OperacionesdeintensidadPanel  matlab.ui.container.Panel
        SustraccinButton              matlab.ui.control.Button
        AdicinButton                  matlab.ui.control.Button
        InversinBinariaButton         matlab.ui.control.Button
        InversinFotogrficaButton      matlab.ui.control.Button
        TransformacionesgeomtricasPanel  matlab.ui.container.Panel
        MosaicoButton                 matlab.ui.control.Button
        EspejoButton                  matlab.ui.control.Button
        RotarImagenButton             matlab.ui.control.Button
        DesplazarImagenButton         matlab.ui.control.Button
        ModificarTamaoButton          matlab.ui.control.Button
        UIAxesProcesada               matlab.ui.control.UIAxes
        UIAxesOriginal                matlab.ui.control.UIAxes
        EcualizacinTab                matlab.ui.container.Tab
        LabelInfoProcesada_2          matlab.ui.control.Label
        LabelInfoOriginal_2           matlab.ui.control.Label
        DeshacerButton_2              matlab.ui.control.Button
        EcualizacinPanel              matlab.ui.container.Panel
        EcualizarButton               matlab.ui.control.Button
        ShrinkButton                  matlab.ui.control.Button
        StrecthButton                 matlab.ui.control.Button
        HistogramaButton              matlab.ui.control.Button
        UIAxesHistogramaProcesada_2   matlab.ui.control.UIAxes
        UIAxesHistograma              matlab.ui.control.UIAxes
        UIAxesProcesada_2             matlab.ui.control.UIAxes
        UIAxesOriginal_2              matlab.ui.control.UIAxes
        UmbralizacinTab               matlab.ui.container.Tab
        LabelInfoProcesada_3          matlab.ui.control.Label
        LabelInfoOriginal_3           matlab.ui.control.Label
        DeshacerButton_3              matlab.ui.control.Button
        UmbralizacinPanel             matlab.ui.container.Panel
        UmbraladaptativoButton        matlab.ui.control.Button
        UmbralFijoButton              matlab.ui.control.Button
        UIAxesHistogramaProcesada_3   matlab.ui.control.UIAxes
        UIAxesHistograma_3            matlab.ui.control.UIAxes
        UIAxesProcesada_3             matlab.ui.control.UIAxes
        UIAxesOriginal_3              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    ImagenOriginal
    ImagenProcesada
    % <-Historil de cada ventana
    HistorialProcesamiento
    HistorialProcesamiento2
    HistorialProcesamiento3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModificarTamaoButton
        function ModificarTamanioButtonPushed(app, event)


    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar nuevo tamaño
    prompt = {'Nuevo ancho:', 'Nuevo alto:'};
    dlgtitle = 'Modificar tamaño';
    dims = [1 35];
    definput = {num2str(size(app.ImagenOriginal, 2)), num2str(size(app.ImagenOriginal, 1))};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    nuevoAncho = str2double(answer{1});
    nuevoAlto = str2double(answer{2});

    if isnan(nuevoAncho) || isnan(nuevoAlto) || nuevoAncho <= 0 || nuevoAlto <= 0
        uialert(app.UIFigure, 'Dimensiones inválidas.', 'Error');
        return;
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end
    

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;


    % Redimensionar imagen
    imagenRedimensionada = imresize(imagenAntes, [nuevoAlto, nuevoAncho]);

    % Mostrar imagen procesada
    app.ImagenProcesada = imagenRedimensionada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar información
    infoProcesada = sprintf('Imagen redimensionada: %d x %d', nuevoAlto, nuevoAncho);
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona una imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img = imread(imgPath);

    % Limpiar todos los ejes
    cla(app.UIAxesOriginal);
    cla(app.UIAxesOriginal_2);
    cla(app.UIAxesOriginal_3);
    cla(app.UIAxesProcesada);
    cla(app.UIAxesProcesada_2);
    cla(app.UIAxesProcesada_3);

    % Reiniciar variables
    app.ImagenOriginal = img;
    app.ImagenProcesada = [];
    app.HistorialProcesamiento = {};
    app.HistorialProcesamiento2 = {};
    app.HistorialProcesamiento3 = {};

    % Mostrar imagen en todos los ejes originales
    imshow(img, 'Parent', app.UIAxesOriginal);
    imshow(img, 'Parent', app.UIAxesOriginal_2);
    imshow(img, 'Parent', app.UIAxesOriginal_3);

    % Obtener dimensiones
    alto = size(img, 1);
    ancho = size(img, 2);

    % Determinar tipo de imagen
    if ndims(img) == 3
        tipo = 'RGB';
    elseif islogical(img)
        tipo = 'Binaria';
    else
        tipo = 'Escala de grises';
    end

    % Obtener nombre y extensión del archivo
    [~, nombreArchivo, extension] = fileparts(file);

    % Crear texto informativo
    infoOriginal = sprintf(['Dimensiones: %d x %d píxeles\n' ...
                            'Tipo de imagen: %s\n' ...
                            'Nombre del archivo: %s\n' ...
                            'Formato: %s'], ...
                            alto, ancho, tipo, nombreArchivo, extension);

    % Actualizar etiquetas en todos los tabs
    app.LabelInfoOriginal.Text = infoOriginal;
    app.LabelInfoOriginal_2.Text = infoOriginal;
    app.LabelInfoOriginal_3.Text = infoOriginal;

    % Reset etiqueta de imagen procesada
    app.LabelInfoProcesada.Text = 'Imagen procesada: -';
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)

    if ~isempty(app.HistorialProcesamiento)
        % Recuperar el último estado
        app.ImagenProcesada = app.HistorialProcesamiento{end};
        app.HistorialProcesamiento(end) = []; % Eliminar el último estado

        % Mostrar la imagen restaurada
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
        app.LabelInfoProcesada.Text = 'Se ha deshecho la última acción.';
    else
        uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
    end
        end

        % Button pushed function: InversinFotogrficaButton
        function InvertirFotograficaButtonPushed(app, event)
    % Verificar si hay una imagen cargada
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end
    
    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end
    
    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;
    
    % Invertir la imagen (funciona para RGB y escala de grises)
    app.ImagenProcesada = 255 - imagenAntes;
    
    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
    
    % Mostrar características de la imagen procesada
    infoProcesada = sprintf('Imagen invertida: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: InversinBinariaButton
        function vertirBinariaButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Convertir a escala de grises si es RGB
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Convertir a binaria usando un umbral (por ejemplo, 128)
    imagenBinaria = imbinarize(imagenGris, 128/255);

    % Invertir la imagen binaria
    imagenInvertida = ~imagenBinaria;

    % Convertir a formato uint8 (0 y 255)
    app.ImagenProcesada = uint8(imagenInvertida) * 255;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen binaria invertida: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: AdicinButton
        function AdicionImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen principal.', 'Error');
        return;
    end

    % Seleccionar segunda imagen
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona la segunda imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img2 = imread(imgPath);

    % Verificar que ambas imágenes tengan el mismo tamaño
    if ~isequal(size(app.ImagenOriginal), size(img2))
        uialert(app.UIFigure, 'Las imágenes deben tener el mismo tamaño y tipo.', 'Error');
        return;
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Realizar la suma y saturar valores mayores a 255
    suma = double(imagenAntes) + double(img2);
    suma(suma > 255) = 255;
    app.ImagenProcesada = uint8(suma);

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen sumada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: GuardarImagenButton
        function GuardarImagenButtonPushed(app, event)

    if isempty(app.ImagenProcesada)
        uialert(app.UIFigure, 'No hay imagen procesada para guardar.', 'Aviso');
        return;
    end

    [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'; '*.tif'}, 'Guardar imagen como');
    if isequal(file, 0)
        return; % El usuario canceló
    end

    imwrite(app.ImagenProcesada, fullfile(path, file));
    uialert(app.UIFigure, 'Imagen guardada exitosamente.', 'Éxito');
        end

        % Button pushed function: DesplazarImagenButton
        function DesplazarImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar desplazamientos en cada dirección
    prompt = {'Pixeles hacia arriba:', 'Pixeles hacia abajo:', ...
              'Pixeles hacia la izquierda:', 'Pixeles hacia la derecha:'};
    dlgtitle = 'Desplazamiento de imagen';
    dims = [1 35];
    definput = {'0', '0', '0', '0'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    arriba = str2double(answer{1});
    abajo = str2double(answer{2});
    izquierda = str2double(answer{3});
    derecha = str2double(answer{4});

    if any(isnan([arriba, abajo, izquierda, derecha]))
        uialert(app.UIFigure, 'Todos los valores deben ser numéricos.', 'Error');
        return;
    end

    % Calcular desplazamiento total en filas y columnas
    desplazamientoFilas = abajo - arriba;
    desplazamientoColumnas = derecha - izquierda;

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Aplicar desplazamiento
    imagenDesplazada = circshift(imagenAntes, [desplazamientoFilas, desplazamientoColumnas]);
    app.ImagenProcesada = imagenDesplazada;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen desplazada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: CalcularDistanciasButton
        function CalcularDistanciasButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar coordenadas de dos píxeles
    prompt = {'Fila píxel p1:', 'Columna píxel p2:', 'Fila píxel q2:', 'Columna píxel q2:'};
    dlgtitle = 'Coordenadas de píxeles';
    dims = [1 35];
    definput = {'1', '1', '10', '10'};
    coords = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(coords)
        return;
    end

    % Convertir a números
    pFila = str2double(coords{1});
    pCol = str2double(coords{2});
    qFila = str2double(coords{3});
    qCol = str2double(coords{4});

    % Validar que estén dentro de la imagen
    [alto, ancho, ~] = size(app.ImagenOriginal);
    if any([pFila, pCol, qFila, qCol] < 1) || ...
       pFila > alto || qFila > alto || ...
       pCol > ancho || qCol > ancho
        uialert(app.UIFigure, 'Las coordenadas deben estar dentro de la imagen.', 'Error');
        return;
    end

    % Calcular distancias
    distEuclidiana = sqrt((pFila - qFila)^2 + (pCol - qCol)^2);
    distD4 = abs(pFila - qFila) + abs(pCol - qCol);
    distD8 = max(abs(pFila - qFila), abs(pCol - qCol));

    % Mostrar resultados
    msg = sprintf(['📏 Distancias entre píxeles:\n\n' ...
                   '🔹 Euclidiana: %.2f píxeles\n' ...
                   '🔹 D4 (Manhattan): %d píxeles\n' ...
                   '🔹 D8 (Chebyshev): %d píxeles'], ...
                   distEuclidiana, distD4, distD8);
    uialert(app.UIFigure, msg, 'Resultados de Distancia');

    % Mostrar puntos y línea en la imagen original
    hold(app.UIAxesOriginal, 'on');
    plot(app.UIAxesOriginal, pCol, pFila, 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Punto p en rojo
    plot(app.UIAxesOriginal, qCol, qFila, 'go', 'MarkerSize', 8, 'LineWidth', 2); % Punto q en verde
    line(app.UIAxesOriginal, [pCol qCol], [pFila qFila], 'Color', 'b', 'LineStyle', '--', 'LineWidth', 1.5); % Línea azul
    hold(app.UIAxesOriginal, 'off');
        end

        % Button pushed function: RotarImagenButton
        function RotarImagenButtonPushed(app, event)
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Lista de ángulos disponibles
    opciones = {'45', '90', '135', '180', '225', '270', '315', '360'};
    [seleccion, ok] = listdlg('PromptString', 'Selecciona el ángulo de rotación:', ...
                              'SelectionMode', 'single', ...
                              'ListString', opciones, ...
                              'Name', 'Rotar imagen');

    if ~ok
        return;
    end

    angulo = str2double(opciones{seleccion});

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Rotar imagen
    imagenRotada = imrotate(imagenAntes, angulo);

    % Recortar imagen rotada al tamaño original
    [alto, ancho] = size(imagenAntes);
    [altoRotado, anchoRotado, ~] = size(imagenRotada);

    % Calcular centro de la imagen rotada
    centroY = floor(altoRotado / 2);
    centroX = floor(anchoRotado / 2);

    % Calcular límites de recorte
    inicioY = max(1, centroY - floor(alto / 2));
    finY = inicioY + alto - 1;
    inicioX = max(1, centroX - floor(ancho / 2));
    finX = inicioX + ancho - 1;

    % Asegurar que no se excedan los límites
    finY = min(finY, altoRotado);
    finX = min(finX, anchoRotado);

    % Recortar imagen rotada
    imagenRotada = imagenRotada(inicioY:finY, inicioX:finX, :);

    % Asignar imagen procesada
    app.ImagenProcesada = imagenRotada;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen rotada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: EspejoButton
        function EspejoImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Opciones de espejo
    opciones = {'Horizontal', 'Vertical', 'Ambos'};
    [seleccion, ok] = listdlg('PromptString', 'Selecciona tipo de espejo:', ...
                              'SelectionMode', 'single', ...
                              'ListString', opciones);

    if ~ok
        return; % Usuario canceló
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Aplicar operación de espejo
    switch opciones{seleccion}
        case 'Horizontal'
            imagenEspejo = fliplr(imagenAntes); % Espejo horizontal
        case 'Vertical'
            imagenEspejo = flipud(imagenAntes); % Espejo vertical
        case 'Ambos'
            imagenEspejo = flipud(fliplr(imagenAntes)); % Espejo horizontal y vertical
    end

    app.ImagenProcesada = imagenEspejo;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen con efecto espejo: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: MosaicoButton
        function MosaicoEspejoButtonPushed(app, event)
    
        if isempty(app.ImagenOriginal)
            uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
            return;
        end
    
        % Usar imagen procesada si existe, si no, usar la original
        if isempty(app.ImagenProcesada)
            imagenAntes = app.ImagenOriginal;
        else
            imagenAntes = app.ImagenProcesada;
        end
    
        % Guardar el estado actual en el historial antes de modificar
        if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
            app.HistorialProcesamiento = {};
        end
        app.HistorialProcesamiento{end+1} = imagenAntes;
    
        % Crear reflejos
        imgH = fliplr(imagenAntes);       % Espejo horizontal
        imgV = flipud(imagenAntes);       % Espejo vertical
        imgHV = flipud(imgH);             % Espejo horizontal + vertical
    
        % Crear mosaico 2x2
        mosaico = [imagenAntes, imgH; imgV, imgHV];
    
        % Redimensionar al tamaño original
        mosaicoFinal = imresize(mosaico, [size(imagenAntes,1), size(imagenAntes,2)]);
    
        % Mostrar imagen procesada
        app.ImagenProcesada = mosaicoFinal;
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
    
        % Mostrar características
        infoProcesada = sprintf('Imagen mosaico: %d x %d', ...
            size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
        app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Convertir a escala de grises si es RGB
    if size(app.ImagenOriginal, 3) == 3
        imagenGris = rgb2gray(app.ImagenOriginal);
    else
        imagenGris = app.ImagenOriginal;
    end

    % Mostrar histograma en UIAxesHistograma
    cla(app.UIAxesHistograma); % Limpiar eje
    histogram(app.UIAxesHistograma, imagenGris(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');
        end

        % Button pushed function: StrecthButton
        function StretchButtonPushed(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma);
    histogram(app.UIAxesHistograma, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');

    % Usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Pedir intervalo al usuario
    prompt = {'Valor mínimo (0-255):', 'Valor máximo (0-255):'};
    dlgtitle = 'Intervalo para Stretch';
    dims = [1 35];
    definput = {'50', '200'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    if isempty(answer)
        return;
    end
    minVal = str2double(answer{1});
    maxVal = str2double(answer{2});
    if isnan(minVal) || isnan(maxVal) || minVal < 0 || maxVal > 255 || minVal >= maxVal
        uialert(app.UIFigure, 'Intervalo inválido. Asegúrate de que 0 ≤ mínimo < máximo ≤ 255.', 'Error');
        return;
    end

    % Guardar el estado actual en el historial del segundo tab
    if isempty(app.HistorialProcesamiento2)
        app.HistorialProcesamiento2 = {};
    end
    app.HistorialProcesamiento2{end+1} = imagenAntes;

    % Aplicar Stretch
    imagenStretch = imadjust(imagenGris, [minVal/255, maxVal/255], [0, 1]);

    % Mostrar imagen modificada
    app.ImagenProcesada = imagenStretch;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);

    % Mostrar histograma de imagen modificada
    cla(app.UIAxesHistogramaProcesada_2);
    histogram(app.UIAxesHistogramaProcesada_2, imagenStretch(:), 256);
    title(app.UIAxesHistogramaProcesada_2, 'Histograma de imagen Stretch');
    xlabel(app.UIAxesHistogramaProcesada_2, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_2, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_2
    infoProcesada = sprintf('Imagen Stretch: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_2.Text = infoProcesada;
        end

        % Button pushed function: ShrinkButton
        function ShrinkButtonPushed(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma);
    histogram(app.UIAxesHistograma, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');

    % Usar imagen procesada si existe, si no, usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Pedir intervalo al usuario
    prompt = {'Nuevo mínimo (0-255):', 'Nuevo máximo (0-255):'};
    dlgtitle = 'Intervalo para Shrink';
    dims = [1 35];
    definput = {'50', '200'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    if isempty(answer)
        return;
    end
    nuevoMin = str2double(answer{1});
    nuevoMax = str2double(answer{2});
    if isnan(nuevoMin) || isnan(nuevoMax) || nuevoMin < 0 || nuevoMax > 255 || nuevoMin >= nuevoMax
        uialert(app.UIFigure, 'Intervalo inválido. Asegúrate de que 0 ≤ mínimo < máximo ≤ 255.', 'Error');
        return;
    end

    % Guardar el estado actual en el historial del segundo tab
    if isempty(app.HistorialProcesamiento2)
        app.HistorialProcesamiento2 = {};
    end
    app.HistorialProcesamiento2{end+1} = imagenAntes;

    % Aplicar Shrink
    imagenShrink = imadjust(imagenGris, stretchlim(imagenGris), [nuevoMin/255, nuevoMax/255]);

    % Mostrar imagen modificada
    app.ImagenProcesada = imagenShrink;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);

    % Mostrar histograma de imagen modificada
    cla(app.UIAxesHistogramaProcesada_2);
    histogram(app.UIAxesHistogramaProcesada_2, imagenShrink(:), 256);
    title(app.UIAxesHistogramaProcesada_2, 'Histograma de imagen Shrink');
    xlabel(app.UIAxesHistogramaProcesada_2, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_2, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_2
    infoProcesada = sprintf('Imagen Shrink: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_2.Text = infoProcesada;
        end

        % Button pushed function: EcualizarButton
        function EcualizarImagenButtonPushed(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma);
    histogram(app.UIAxesHistograma, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');

    % Usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Guardar el estado actual en el historial del segundo tab
    if isempty(app.HistorialProcesamiento2)
        app.HistorialProcesamiento2 = {};
    end
    app.HistorialProcesamiento2{end+1} = imagenAntes;

    % Aplicar ecualización
    imagenEcualizada = histeq(imagenGris);

    % Mostrar imagen ecualizada
    app.ImagenProcesada = imagenEcualizada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);

    % Mostrar histograma de imagen ecualizada
    cla(app.UIAxesHistogramaProcesada_2);
    histogram(app.UIAxesHistogramaProcesada_2, imagenEcualizada(:), 256);
    title(app.UIAxesHistogramaProcesada_2, 'Histograma de imagen ecualizada');
    xlabel(app.UIAxesHistogramaProcesada_2, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_2, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_2
    infoProcesada = sprintf('Imagen ecualizada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_2.Text = infoProcesada;
        end

        % Button pushed function: UmbralFijoButton
        function UmbralFijoButtonPushe(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_3);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma_3);
    histogram(app.UIAxesHistograma_3, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma_3, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma_3, 'Intensidad');
    ylabel(app.UIAxesHistograma_3, 'Frecuencia');

    % usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Solicitar valor de umbral
    prompt = {'Ingresa el valor de umbral (0-255):'};
    dlgtitle = 'Umbralización fija';
    dims = [1 35];
    definput = {'128'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    if isempty(answer)
        return;
    end
    umbral = str2double(answer{1});
    if isnan(umbral) || umbral < 0 || umbral > 255
        uialert(app.UIFigure, 'El valor debe estar entre 0 y 255.', 'Error');
        return;
    end

    % Guardar el estado actual en el historial del tercer tab
    if isempty(app.HistorialProcesamiento3)
        app.HistorialProcesamiento3 = {};
    end
    app.HistorialProcesamiento3{end+1} = imagenAntes;

    % Aplicar umbralización
    imagenBinaria = imbinarize(imagenGris, umbral / 255);
    imagenUmbralizada = uint8(imagenBinaria) * 255;

    % Mostrar imagen umbralizada
    app.ImagenProcesada = imagenUmbralizada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);

    % Mostrar histograma de imagen umbralizada
    cla(app.UIAxesHistogramaProcesada_3);
    histogram(app.UIAxesHistogramaProcesada_3, imagenUmbralizada(:), 256);
    title(app.UIAxesHistogramaProcesada_3, 'Histograma de imagen umbralizada');
    xlabel(app.UIAxesHistogramaProcesada_3, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_3, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_3
    infoProcesada = sprintf('Imagen umbralizada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_3.Text = infoProcesada;
        end

        % Button pushed function: UmbraladaptativoButton
        function UmbralAdaptativoButtonPushed(app, event)
     % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_3);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma_3);
    histogram(app.UIAxesHistograma_3, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma_3, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma_3, 'Intensidad');
    ylabel(app.UIAxesHistograma_3, 'Frecuencia');

    % Usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Guardar el estado actual en el historial del tercer tab
    if isempty(app.HistorialProcesamiento3)
        app.HistorialProcesamiento3 = {};
    end
    app.HistorialProcesamiento3{end+1} = imagenAntes;

    % Aplicar umbralización adaptativa
    umbralAdaptativo = adaptthresh(imagenGris, 0.5); % Puedes ajustar la sensibilidad
    imagenBinaria = imbinarize(imagenGris, umbralAdaptativo);
    imagenUmbralizada = uint8(imagenBinaria) * 255;

    % Mostrar imagen umbralizada
    app.ImagenProcesada = imagenUmbralizada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);

    % Mostrar histograma de imagen umbralizada
    cla(app.UIAxesHistogramaProcesada_3);
    histogram(app.UIAxesHistogramaProcesada_3, imagenUmbralizada(:), 256);
    title(app.UIAxesHistogramaProcesada_3, 'Histograma de imagen umbralizada');
    xlabel(app.UIAxesHistogramaProcesada_3, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_3, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_3
    infoProcesada = sprintf('Imagen umbralizada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_3.Text = infoProcesada;
        end

        % Button pushed function: SustraccinButton
        function SustraccionImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen principal.', 'Error');
        return;
    end

    % Seleccionar segunda imagen
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona la segunda imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img2 = imread(imgPath);

    % Verificar que ambas imágenes tengan el mismo tamaño
    if ~isequal(size(app.ImagenOriginal), size(img2))
        uialert(app.UIFigure, 'Las imágenes deben tener el mismo tamaño y tipo.', 'Error');
        return;
    end

    % Realizar la sustracción usando diferencia absoluta
    resta = abs(double(app.ImagenOriginal) - double(img2));

    % Normalizar para mejorar contraste visual
    resta = uint8(255 * mat2gray(resta));
    app.ImagenProcesada = resta;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

        end

        % Button pushed function: DeshacerButton_2
        function DeshacerButtonPushed_2(app, event)

     if ~isempty(app.HistorialProcesamiento2)
            % Recuperar el último estado
            app.ImagenProcesada = app.HistorialProcesamiento2{end};
            app.HistorialProcesamiento2(end) = [];
    
            % Mostrar la imagen restaurada en el segundo tab
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);
    
            % Limpiar el histograma procesado
            cla(app.UIAxesHistogramaProcesada_2);
            title(app.UIAxesHistogramaProcesada_2, '');
            xlabel(app.UIAxesHistogramaProcesada_2, '');
            ylabel(app.UIAxesHistogramaProcesada_2, '');
    
            % Mensaje informativo
            app.LabelInfoProcesada.Text = 'Se ha deshecho la última acción.';
        else
            uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
     end
        end

        % Button pushed function: DeshacerButton_3
        function DeshacerButtonPushed_3(app, event)
            
     if ~isempty(app.HistorialProcesamiento3)
            % Recuperar el último estado
            app.ImagenProcesada = app.HistorialProcesamiento3{end};
            app.HistorialProcesamiento3(end) = [];
    
            % Mostrar la imagen restaurada en el 3 tab
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);
    
            % Limpiar el histograma procesado
            cla(app.UIAxesHistogramaProcesada_3);
            title(app.UIAxesHistogramaProcesada_3, '');
            xlabel(app.UIAxesHistogramaProcesada_3, '');
            ylabel(app.UIAxesHistogramaProcesada_3, '');
    
            % Mensaje informativo
            app.LabelInfoProcesada.Text = 'Se ha deshecho la última acción.';
        else
            uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
     end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OperacionesBsicasTab = uitab(app.TabGroup);
            app.OperacionesBsicasTab.Title = ' Operaciones Básicas';
            app.UIAxesOriginal = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesOriginal, 'Imagen Original')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesProcesada = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesProcesada, 'Imagen Procesada')
            app.UIAxesProcesada.XTick = [];
            app.UIAxesProcesada.YTick = [];
            app.TransformacionesgeomtricasPanel = uipanel(app.OperacionesBsicasTab);
            app.TransformacionesgeomtricasPanel.Title = ' Transformaciones geométricas';
            app.ModificarTamaoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.ModificarTamaoButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarTamanioButtonPushed, true);
            app.ModificarTamaoButton.Text = ' Modificar Tamaño';
            app.DesplazarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.DesplazarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @DesplazarImagenButtonPushed, true);
            app.DesplazarImagenButton.Text = 'Desplazar Imagen';
            app.RotarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.RotarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @RotarImagenButtonPushed, true);
            app.RotarImagenButton.Text = 'Rotar Imagen';
            app.EspejoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.EspejoButton.ButtonPushedFcn = createCallbackFcn(app, @EspejoImagenButtonPushed, true);
            app.EspejoButton.Text = 'Espejo';
            app.MosaicoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.MosaicoButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicoEspejoButtonPushed, true);
            app.MosaicoButton.Text = 'Mosaico';
            app.OperacionesdeintensidadPanel = uipanel(app.OperacionesBsicasTab);
            app.OperacionesdeintensidadPanel.Title = 'Operaciones de intensidad';
            app.InversinFotogrficaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinFotogrficaButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirFotograficaButtonPushed, true);
            app.InversinFotogrficaButton.Text = 'Inversión Fotográfica';
            app.InversinBinariaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinBinariaButton.ButtonPushedFcn = createCallbackFcn(app, @vertirBinariaButtonPushed, true);
            app.InversinBinariaButton.Text = 'Inversión Binaria';
            app.AdicinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.AdicinButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionImagenButtonPushed, true);
            app.AdicinButton.Text = 'Adición';
            app.SustraccinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.SustraccinButton.ButtonPushedFcn = createCallbackFcn(app, @SustraccionImagenButtonPushed, true);
            app.SustraccinButton.Text = 'Sustracción';
            app.AnlisisdepxelesPanel = uipanel(app.OperacionesBsicasTab);
            app.AnlisisdepxelesPanel.Title = 'Análisis de píxeles';
            app.CalcularDistanciasButton = uibutton(app.AnlisisdepxelesPanel, 'push');
            app.CalcularDistanciasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDistanciasButtonPushed, true);
            app.CalcularDistanciasButton.Text = 'Calcular Distancias';
            app.LabelInfoOriginal = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoOriginal.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoProcesada.Text = 'LabelInfoProcesada';
            app.DeshacerButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer';
            app.EcualizacinTab = uitab(app.TabGroup);
            app.EcualizacinTab.Title = 'Ecualización';
            app.UIAxesOriginal_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesOriginal_2, 'Imagen Original')
            app.UIAxesOriginal_2.XTick = [];
            app.UIAxesOriginal_2.YTick = [];
            app.UIAxesProcesada_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesProcesada_2, 'Imagen Procesada')
            app.UIAxesProcesada_2.XTick = [];
            app.UIAxesProcesada_2.YTick = [];
            app.UIAxesHistograma = uiaxes(app.EcualizacinTab);
            title(app.UIAxesHistograma, 'Histograma')
            app.UIAxesHistograma.XTick = [];
            app.UIAxesHistograma.YTick = [];
            app.UIAxesHistogramaProcesada_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesHistogramaProcesada_2, 'Histograma')
            app.UIAxesHistogramaProcesada_2.XTick = [];
            app.UIAxesHistogramaProcesada_2.YTick = [];
            app.EcualizacinPanel = uipanel(app.EcualizacinTab);
            app.EcualizacinPanel.Title = 'Ecualización	';
            app.HistogramaButton = uibutton(app.EcualizacinPanel, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma	';
            app.StrecthButton = uibutton(app.EcualizacinPanel, 'push');
            app.StrecthButton.ButtonPushedFcn = createCallbackFcn(app, @StretchButtonPushed, true);
            app.StrecthButton.Text = 'Strecth';
            app.ShrinkButton = uibutton(app.EcualizacinPanel, 'push');
            app.ShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkButtonPushed, true);
            app.ShrinkButton.Text = 'Shrink';
            app.EcualizarButton = uibutton(app.EcualizacinPanel, 'push');
            app.EcualizarButton.ButtonPushedFcn = createCallbackFcn(app, @EcualizarImagenButtonPushed, true);
            app.EcualizarButton.Text = 'Ecualizar';
            app.DeshacerButton_2 = uibutton(app.EcualizacinTab, 'push');
            app.DeshacerButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_2, true);
            app.DeshacerButton_2.Text = 'Deshacer';
            app.LabelInfoOriginal_2 = uilabel(app.EcualizacinTab);
            app.LabelInfoOriginal_2.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_2 = uilabel(app.EcualizacinTab);
            app.LabelInfoProcesada_2.Text = 'LabelInfoProcesada';
            app.UmbralizacinTab = uitab(app.TabGroup);
            app.UmbralizacinTab.Title = 'Umbralización';
            app.UIAxesOriginal_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesOriginal_3, 'Imagen Original')
            app.UIAxesOriginal_3.XTick = [];
            app.UIAxesOriginal_3.YTick = [];
            app.UIAxesProcesada_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesProcesada_3, 'Imagen Procesada')
            app.UIAxesProcesada_3.XTick = [];
            app.UIAxesProcesada_3.YTick = [];
            app.UIAxesHistograma_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesHistograma_3, 'Histograma')
            app.UIAxesHistograma_3.XTick = [];
            app.UIAxesHistograma_3.YTick = [];
            app.UIAxesHistogramaProcesada_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesHistogramaProcesada_3, 'Histograma')
            app.UIAxesHistogramaProcesada_3.XTick = [];
            app.UIAxesHistogramaProcesada_3.YTick = [];
            app.UmbralizacinPanel = uipanel(app.UmbralizacinTab);
            app.UmbralizacinPanel.Title = 'Umbralización	';
            app.UmbralFijoButton = uibutton(app.UmbralizacinPanel, 'push');
            app.UmbralFijoButton.ButtonPushedFcn = createCallbackFcn(app, @UmbralFijoButtonPushe, true);
            app.UmbralFijoButton.Text = 'Umbral Fijo';
            app.UmbraladaptativoButton = uibutton(app.UmbralizacinPanel, 'push');
            app.UmbraladaptativoButton.ButtonPushedFcn = createCallbackFcn(app, @UmbralAdaptativoButtonPushed, true);
            app.UmbraladaptativoButton.Text = 'Umbral adaptativo';
            app.DeshacerButton_3 = uibutton(app.UmbralizacinTab, 'push');
            app.DeshacerButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_3, true);
            app.DeshacerButton_3.Text = 'Deshacer';
            app.LabelInfoOriginal_3 = uilabel(app.UmbralizacinTab);
            app.LabelInfoOriginal_3.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_3 = uilabel(app.UmbralizacinTab);
            app.LabelInfoProcesada_3.Text = 'LabelInfoProcesada';
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.PROCESADORDEIMAGENESLabel = uilabel(app.UIFigure);
            app.PROCESADORDEIMAGENESLabel.Text = 'PROCESADOR DE IMAGENES';
            app.GuardarImagenButton = uibutton(app.UIFigure, 'push');
            app.GuardarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarImagenButtonPushed, true);
            app.GuardarImagenButton.Text = 'Guardar Imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1___7Equipo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end